classdef Audiogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudiogramApp      matlab.ui.Figure
        ButtonRozpocznij  matlab.ui.control.Button
        ButtonSlysze      matlab.ui.control.Button
        ButtonPorownaj    matlab.ui.control.Button
        ButtonPrevious    matlab.ui.control.Button
        ButtonNext        matlab.ui.control.Button
        ImageCelebrity    matlab.ui.control.Image
        LabelAudiogram    matlab.ui.control.Label
        AxesAudiogram     matlab.ui.control.UIAxes
    end

  
    properties (Access = private)
        
        f_audiogram = [125 500 1000 2000 3000 4000 6000 8000 100000]; % 9 czestotliwosci audiogramu

        coef_audiogram = [1]; % wspolczynniki (wzmacniajace n*10dB); od 0dB do +140dB; Beda wypelnione po starcie programu
        
        offset = 0; % sztywno ustawiany Offset uszkodzenia sluchu, np. 7dB (bo 0dB na 1kHz jest glupie)
        
        left_ear = []; % ubytki sluchu w lewym uchu (w dB) dla 9 czestotliwosci audiogramu
        right_ear = []; % ubytki sluchu w prawym uchu (w dB) dla 9 czestotliwosci audiogramu
        left_ear_score = 0; % wynik audiogramu w lewym uchu [dB]
        right_ear_score = 0; % wynik audiogramu w lewym uchu [dB]
        final_score = 0; % wynik audiogramu uogolniony
        
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % Wypelnienie tablicy wspolczynnikow wzmocnienia
            for i = 1:14 %wypelnienie
                app.coef_audiogram(i+1) = 10.^(i*10/20);
            end
            
            
            % Uniwersalny generator
            A = 0.01; % stale wzmocnienie (zeby nie ogluchnac przy testach); domyslnie bylo 1
            f = f_audiogram(1); % czestotliwosc generowanego dzwieku [Hz]
            fs = 48000; % czestotliwosc probkowania [Hz]
            t = 0:1/fs:1; % probki czasowe 1 sekundy
%             gain = 0; % wzmocnienie dB; Do wzmacniania kolejnych 
            coef = coef_audiogram(1); % wspolczynnik (zwiekszajacy +dB)
            y=coef*A*sin(2*pi*f*t);  % sygnal harmoniczny
            
            % Lewy kanal
            left=y(1,1:48000);
            left = [left; zeros(size(left))];
            % generowanie 10 sekund w lewym kanale
            left_10sec = []
            for i = 1:10
                left_10sec = [left_10sec left];
            end
            sound(left_10sec, fs);
            
%             % Prawy kanal
%             right=y(1,1:48000);
%             right = [zeros(size(right)); right];
%             % generowanie 10 sekund w prawym kanale
%             right_10sec = []
%             for i = 1:10
%                 right_10sec = [right_10sec right];
%             end
%             sound(right_10sec, fs);


            % NAPISZ KALIBRACJE OD NOWA NP. Z UZYCIEM GENERATORA
            %kalibracja
            fs=48000; % f probkowania 
            f=1000; % f generowanego dzwieku
            t=0:1/fs:1; % probki czasowe
            A=0.01; % domyslnie bylo 1
            y=A*sin(2*pi*f*t);  % sygnal harmoniczny
            Py=10*log10(sum(y.^2)); % moc akustyczna
            coef=10.^(5/20); % wspolczynnik (zwiekszajacy +dB)
            yy=y.*coef; % wzmocnienie sygnalu o +5dB
            Pyy=10*log10(sum(yy.^2)); % moc akustyczna +5dB
            test=[]; % dzwiek testowy do kalibracji
            
            % generowanie 2*10 sekund sygnalu testowego do kalibracji
            for i=1:10
                z1=y(1,1:48000);
                z2=yy(1,1:48000);
                test=[zz z1 z2];
            end
            
            %z4=zz/max(abs(zz));
            %plot(z4)
            %audiowrite('test.wav', zz, fs); % zapisanie sygnalu testowego
            %do pliku
         
            
            sound(test, fs); %dzwiek testowy
            h=questdlg('Kliknij, gdy będziesz słyszeć tylko jeden z dwóch tonów', ...
                'Kalibracja','OK','OK');
            switch h
              case 'OK'
                 %'OK' code here
                 clear("sound");
              case ''
                 %abort code
              otherwise
            end


%             f = msgbox("Witam", "Kalibracja");
%             while (f == true)
%                 sound(zz, fs);
%             end
            %f = msgbox("Witam", "Kalibracja");
        end

        % Button pushed function: ButtonRozpocznij
        function CallbackRozpocznij(app, event)
            
            % Procedura wykonania audiogramu - DOPISAC
            
            % Kanał lewy - wykorzystac generator i 2 petle (czestotliwosci i wzmocnienia
            
            % Kanal prawy - wykorzystac generator i 2 petle (czestotliwosci i wzmocnienia
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudiogramApp = uifigure('Visible', 'off');
            app.AudiogramApp.Name = 'MATLAB App';
            app.AxesAudiogram = uiaxes(app.AudiogramApp);
            title(app.AxesAudiogram, 'Title')
            xlabel(app.AxesAudiogram, 'Częstotliwość [Hz]')
            ylabel(app.AxesAudiogram, 'Ubytek słuchu [dB]')
            zlabel(app.AxesAudiogram, 'Z')
            app.AxesAudiogram.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.LabelAudiogram = uilabel(app.AudiogramApp);
            app.LabelAudiogram.Text = 'Audiogram';
            app.ImageCelebrity = uiimage(app.AudiogramApp);
            app.ButtonNext = uibutton(app.AudiogramApp, 'push');
            app.ButtonPrevious = uibutton(app.AudiogramApp, 'push');
            app.ButtonPorownaj = uibutton(app.AudiogramApp, 'push');
            app.ButtonPorownaj.Text = 'Porównaj';
            app.ButtonSlysze = uibutton(app.AudiogramApp, 'push');
            app.ButtonSlysze.Text = 'Słyszę';
            app.ButtonRozpocznij = uibutton(app.AudiogramApp, 'push');
            app.ButtonRozpocznij.ButtonPushedFcn = createCallbackFcn(app, @CallbackRozpocznij, true);
            app.ButtonRozpocznij.Text = 'Rozpocznij test';
            % Show the figure after all components are created
            app.AudiogramApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audiogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudiogramApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudiogramApp)
        end
    end
end